classdef artifactDetectViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        DataLoadingPanel                matlab.ui.container.Panel
        loadmatdataButton               matlab.ui.control.Button
        ArtifactDisplaySettingsPanel    matlab.ui.container.Panel
        XminEditField                   matlab.ui.control.NumericEditField
        XminEditFieldLabel              matlab.ui.control.Label
        XmaxEditField                   matlab.ui.control.NumericEditField
        XmaxEditFieldLabel              matlab.ui.control.Label
        YminEditField                   matlab.ui.control.NumericEditField
        YminEditFieldLabel              matlab.ui.control.Label
        YmaxEditField                   matlab.ui.control.NumericEditField
        YmaxEditFieldLabel              matlab.ui.control.Label
        ArtifactWaveformDetectionPanel  matlab.ui.container.Panel
        mininterpulsesampEditField      matlab.ui.control.NumericEditField
        mininterpulsesampEditFieldLabel  matlab.ui.control.Label
        LoadArtifactDetectionsButton    matlab.ui.control.Button
        ArtifactWindowsizeEditField     matlab.ui.control.NumericEditField
        ArtifactWindowsizeEditFieldLabel  matlab.ui.control.Label
        DetectionoffsetEditField        matlab.ui.control.NumericEditField
        DetectionoffsetEditFieldLabel   matlab.ui.control.Label
        DetectArtifactsButton           matlab.ui.control.Button
        maxEditField                    matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        samplelagbetweenthreshcrossesLabel  matlab.ui.control.Label
        Thresh2CrossButtonGroup         matlab.ui.container.ButtonGroup
        fallingButton_2                 matlab.ui.control.ToggleButton
        risingButton_2                  matlab.ui.control.ToggleButton
        Threshold2EditField             matlab.ui.control.NumericEditField
        Threshold2EditFieldLabel        matlab.ui.control.Label
        Thresh1CrossButtonGroup         matlab.ui.container.ButtonGroup
        fallingButton                   matlab.ui.control.ToggleButton
        risingButton                    matlab.ui.control.ToggleButton
        Threshold1EditField             matlab.ui.control.NumericEditField
        Threshold1EditFieldLabel        matlab.ui.control.Label
        DataFilteringPanel              matlab.ui.container.Panel
        endzeropadsampsEditField        matlab.ui.control.NumericEditField
        endzeropadsampsEditFieldLabel   matlab.ui.control.Label
        filterButton                    matlab.ui.control.Button
        higphasscutoffhzEditField       matlab.ui.control.NumericEditField
        higphasscutoffhzEditFieldLabel  matlab.ui.control.Label
        datasamplessecEditField         matlab.ui.control.NumericEditField
        datasamplessecEditFieldLabel    matlab.ui.control.Label
        optionalLabel                   matlab.ui.control.Label
        PlotrawfiltdataButton           matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        artifactdetectionfileEditField  matlab.ui.control.EditField
        artifactdetectionfileEditFieldLabel  matlab.ui.control.Label
        matdatafileEditField            matlab.ui.control.EditField
        matdatafileEditFieldLabel       matlab.ui.control.Label
        DetectedArtifactDisplayNavigationPanel  matlab.ui.container.Panel
        NumofdetectedartsEditField      matlab.ui.control.NumericEditField
        NumofdetectedartsEditFieldLabel  matlab.ui.control.Label
        thruEditField                   matlab.ui.control.NumericEditField
        thruEditFieldLabel              matlab.ui.control.Label
        DisplayingDetectionsEditField   matlab.ui.control.NumericEditField
        DisplayingDetectionsEditFieldLabel  matlab.ui.control.Label
        NdetectionstodisplayEditField   matlab.ui.control.NumericEditField
        NdetectionstodisplayEditFieldLabel  matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        Menu                            matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        loadsettingsMenu_2              matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        saveartifactdetectionsMenu      matlab.ui.container.Menu
        savesettingsMenu                matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        rawdata % 1 x N vector of time-series data
        procdata % based off of rawdata, the user may decide to filter the rawdata for display
        pks % vector of detected positive and negative peaks in the data
        threshCrossEdge = 'falling'; % string, falling | rising, r 
        thresh = -100; % threshold detection level for artifact crossing
        nArts = 0; % keeping track of number of detected artifacts
%         detectArtSegDispYmin = 0; % Description
%         detectArtSegDispYmax = 1; % Description
        
        sampArt % A vector of sample indices where artifacts where detected
        segs % an n x m matrix of data containing segments of data in which artifacts were detected, where n is the number of detections and m is the length in samples of each segment of data
        nsegDisp = 1000; % number of segments to display at any given moment
        segRangeDisp = [1 1000]; % a 1 x 2 integer vector, describing the first and last detected segment currently to be displayed in the artifact detection window
        artLen % the length in samples of each artifact detection
    end 
    
    methods (Access = private)
        
        function app = updatePeaksHistogram(app)
            [pkspos,~] = findpeaks(app.procdata(app.procdata>0));
            [pksneg,~] = findpeaks(-app.procdata(app.procdata<0));
            app.pks = [pkspos, -pksneg];
            histogram(app.UIAxes2,app.pks);
            
        end
        
        function idxArt = detectArt(app)
            idxAbove = app.procdata > app.thresh;
            idxBelow = app.procdata < app.thresh;
            idxDetect = idxAbove + -idxBelow;
            idxCross = [0 diff(idxDetect)];
            
            switch app.threshCrossEdge
                case 'falling'
                    idxArt = idxCross == -2;
                    
                case 'rising'
                    idxArt = idxCross == 2;
                    
                otherwise
                    error('Enter 3rd input as string, either "falling" or "rising"!');
                    
            end
            
        end
        
        function [Segs, SegIndices] = gatherSegments(app, refs, segLength)
           % Organize a single-channel time-series into segments based off of
            % periodic reference events. Note: window idicies must not exceed the
            % indices of the original signal. 
            %
            % [Segs,SegIndices] = segments(sig,refs,segLength)
            %       Output is an m-by-n matrix of segment windows according to "m"
            %       number of reference events, as well as the indices of these
            %       segments from the original time-series vector. Window-boundaries:
            %       ref-event + "segLength" to the right.
            % [Segs,SegIndices] = segments(sig,refs,[loffset, roffset])
            %       Same as above, except that segment windows boundaries are defined
            %       "loffset" samples to the left of ref-event and "roffset" samples to
            %       the right of ref-event.
            
            if ~isvector(app.procdata), error('input "sig" must be a single vector value!'); end
            if ~isvector(refs), error('input "refs" must be a single vector vaclue!'); end
            
            %check segLength input
            if length(segLength)>1
                if length(segLength)>2
                    error('input exceeds allowed dimensions');
                end
                loffset = segLength(1);
                roffset = segLength(2);
            elseif isscalar(segLength)
                loffset = 0;
                roffset = segLength-1;
            else
                error('improper input for segLength')
            end
            
            refCount = length(refs);
            Segs = zeros(refCount,loffset+1+roffset);
            SegIndices = zeros(refCount,loffset+1+roffset);
            
            for i = 1:refCount
                SegIndices(i,:) = (refs(i)-loffset):(refs(i)+roffset);
                Segs(i,:) = app.procdata(SegIndices(i,:));
            end  
            % if nargout == 1
            %     varargout{1} = SegIndices;
            % end
            
        end
        
        function app = updateArtSegmentsPlot(app)
            r = app.segRangeDisp;
            segsub = app.segs(r(1):r(2), :);
            app.UIAxes.YLim = [app.YminEditField.Value, app.YmaxEditField.Value];
            plot(app.UIAxes, segsub');
%             app.detectArtSegDispYmin = app.UIAxes.YLim(1);
%             app.detectArtSegDispYmax = app.UIAxes.YLim(2);
            
        end
        
        function updateNumDetectedArtsDisp(app)
            app.NumofdetectedartsEditField.Value = app.nArts;
            
        end
        
        function idxThrx = detectThrx(app, data, thresh, xcase)
            % outputs detections of the threshold crossing, with the index
            % position of the original data being a -2 if the threshold
            % crossing is falling vs +2 if the crossing is rising
            idxAbove = data > thresh;
            idxBelow = data <= thresh;
            idxDetect = idxAbove + -idxBelow;
            idxThrx = [0 diff(idxDetect)];
            
            switch xcase
                case 'falling'
                    idxThrx = (idxThrx == -2);
                    
                case 'rising'
                    idxThrx = (idxThrx == 2);
                    
                otherwise
                    error('Enter 3rd input as string, either "falling" or "rising"!');
                    
            end
            
        end
        
        function idxDet = twoStepDetection(app, idx1, idx2, min, max)
            idxDet = zeros(size(idx1));
            
            % go thru each value in idx1
            for i = 1:length(idx1)
                if idx1(i) == 1
                    % is there at least 1 idx2 detection that falls within
                    % the min/max range?
                    test = idx2(i+min:i+max);
                    if any(test)
                        idxDet(i) = true;
                    end
                    
                end
                
                         
            end
            
        end
        
        function updateArtifactDetect(app)
            % Tally up artifact segmetns and update artifact-related
            % displays
            cla(app.UIAxes2);
            f = msgbox('Detecting artifacts...');

            % gather segments of data around detections
            [app.segs, ~] = gatherSegments(app, app.sampArt, ...
                app.artLen);
            app.nArts = size(app.segs, 1);
            
            % update the plot of segments
            updateArtSegmentsPlot(app);
            
            % update the number of detected artifacts display
            updateNumDetectedArtsDisp(app);
            
            % update Inter-stimulus interval display
            ISI = diff(app.sampArt);
            histogram(app.UIAxes3,ISI);
            
            close(f);
            
            
        end
        
        function updateSegRangeDisp(app, nsegChange)
            % update the range of detected artifact segments to display,
            % based on the "nsegDisp" and "segs" properties limitations
            app.segRangeDisp(1) = app.segRangeDisp(1) + nsegChange;
            if app.segRangeDisp(1) < 1
                app.segRangeDisp(1) = 1;
                
            elseif app.segRangeDisp(1) >= size(app.segs,1)
                app.segRangeDisp(1) = size(app.segs,1) - 1;
                
            end
            
            app.segRangeDisp(2) = app.segRangeDisp(1) + app.nsegDisp;
            
            if app.segRangeDisp(2) > size(app.segs,1)
                app.segRangeDisp(2) = size(app.segs,1);
            end
            
            app.DisplayingDetectionsEditField.Value = app.segRangeDisp(1);
            app.thruEditField.Value = app.segRangeDisp(2);
            
            
        end
        
        function idxArt = rejectExtraDetect(app, idxArt, minInterpulse)
            for i = 1:length(idxArt)
                if idxArt(i) == 1
                    idxArt(i+1:i+minInterpulse) = 0;
                    
                end
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function loadmatdataMenuSelected(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            
            [file, path] = uigetfile;
            load([path file], 'data');
            app.rawdata = data;
            app.procdata = app.rawdata;
            updatePeaksHistogram(app);
            app.PlotrawfiltdataButton.Enable = true;
            app.DetectArtifactsButton.Enable = true;
            app.filterButton.Enable = true;
            
        end

        % Button pushed function: DetectArtifactsButton
        function DetectArtifactsButtonPushed(app, event)
            % detect artifacts by threshold crossings
%             idxArt = detectArt(app);
            
            % detect threshold 1 crossings
            idxThrx1 = detectThrx(app, app.procdata, ...
                app.Threshold1EditField.Value, ...
                app.Thresh1CrossButtonGroup.SelectedObject.Text);
          
            % detect threshold 2 crossings
            idxThrx2 = detectThrx(app, app.procdata, ...
                app.Threshold2EditField.Value, ...
                app.Thresh2CrossButtonGroup.SelectedObject.Text);
            
            % for each detected threshold 1, track which ones are followed
            % by a threshold 2 within the allowed min/man lag-range; these
            % are the "true" artifact detections
            idxArt = twoStepDetection(app, idxThrx1, idxThrx2, ...
                app.minEditField.Value, app.maxEditField.Value);
            
            idxArt = rejectExtraDetect(app, idxArt, app.mininterpulsesampEditField.Value);
            
            app.sampArt = find(idxArt);
            
            % offset the detected indices according to user input
            app.sampArt = app.sampArt + app.DetectionoffsetEditField.Value;
%             app.artLen = median(diff(app.sampArt));
            app.artLen = app.ArtifactWindowsizeEditField.Value;
            
            % Tally up artifact segmetns and update artifact-related
            % displays
            updateArtifactDetect(app);
            
        end

        % Value changed function: YmaxEditField
        function YmaxEditFieldValueChanged(app, event)
%             app.detectArtSegDispYmax = app.YmaxEditField.Value;
            app.UIAxes.YLim(2) = app.YmaxEditField.Value;
            
        end

        % Value changed function: YminEditField
        function YminEditFieldValueChanged(app, event)
%             app.detectArtSegDispYmin = app.YminEditField.Value;
            app.UIAxes.YLim(1) = app.YminEditField.Value;
            
        end

        % Button pushed function: PlotrawfiltdataButton
        function PlotrawfiltdataButtonPushed(app, event)
            figure; 
            plot(app.procdata);
            
        end

        % Button pushed function: filterButton
        function filterButtonPushed(app, event)
            f = msgbox('filtering...');
            
            % zero-pad the end of the data if specified by user
            app.rawdata = [app.rawdata, zeros(1, app.endzeropadsampsEditField.Value)];
            app.procdata = [app.procdata, zeros(1, app.endzeropadsampsEditField.Value)];
                
            % filter the data according to specified fields
            fs = app.datasamplessecEditField.Value; % samples / sec
            fc = app.higphasscutoffhzEditField.Value;
            [b, a] = butter(2, fc/(fs/2), 'high');
            app.procdata = filtfilt(b, a, app.procdata);  
            
            % re-gather data peaks and display in the histogram
            updatePeaksHistogram(app);
            
            close(f);
            
        end

        % Button pushed function: LoadArtifactDetectionsButton
        function LoadArtifactDetectionsButtonPushed(app, event)
            [file, path] = uigetfile();
            app.artifactdetectionfileEditField.Value = file;
            detections = load([path file]);
            D = struct2cell(detections);
            app.sampArt = D{1,1};
%             app.artLen = median(diff(app.sampArt));
            app.artLen = app.ArtifactWindowsizeEditField.Value;   
            
            updateArtifactDetect(app);
            
        end

        % Callback function
        function loadmatdataMenu_2Selected(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            
            
            [file, path] = uigetfile;
            f = msgbox('loading data...');

            load([path file], 'data');
            app.rawdata = data;
            app.procdata = app.rawdata;
            updatePeaksHistogram(app);
            app.PlotrawfiltdataButton.Enable = true;
            app.DetectArtifactsButton.Enable = true;
            app.filterButton.Enable = true;
            app.LoadArtifactDetectionsButton.Enable = true;
            
            close(f);
            
        end

        % Menu selected function: saveartifactdetectionsMenu
        function saveartifactdetectionsMenuSelected(app, event)
            [file,path] = uiputfile('*.mat');
            detections = app.sampArt;
            save([path file], 'detections', '-mat');
            
        end

        % Value changed function: ArtifactWindowsizeEditField
        function ArtifactWindowsizeEditFieldValueChanged(app, event)
            app.artLen = app.ArtifactWindowsizeEditField.Value;
            
        end

        % Value changed function: NdetectionstodisplayEditField
        function NdetectionstodisplayEditFieldValueChanged(app, event)
            app.nsegDisp = app.NdetectionstodisplayEditField.Value;
            
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            updateSegRangeDisp(app, -app.nsegDisp);
            updateArtSegmentsPlot(app);
           
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            updateSegRangeDisp(app, app.nsegDisp);
            updateArtSegmentsPlot(app);
        end

        % Value changed function: thruEditField
        function thruEditFieldValueChanged(app, event)
            value = app.thruEditField.Value;
            
        end

        % Value changed function: DisplayingDetectionsEditField
        function DisplayingDetectionsEditFieldValueChanged(app, event)
            value = app.DisplayingDetectionsEditField.Value;
            app.segRangeDisp(1) = value;
            app.segRangeDisp(2) = value + app.nsegDisp;
            updateSegRangeDisp(app,0);
            updateArtSegmentsPlot(app);
            
        end

        % Button pushed function: loadmatdataButton
        function loadmatdataButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            
            
            [file, path] = uigetfile;
            f = msgbox('loading data...');
            app.matdatafileEditField.Value = file;
            
            data = load([path file]);
            D = struct2cell(data);
            inputdata = D{1,1};
            
            % enforce that the rawdata should be a 1xN vector
            if size(inputdata,1) > size(inputdata,2)
                inputdata = inputdata';
            end
            
            app.rawdata = inputdata;
            app.procdata = app.rawdata;
            updatePeaksHistogram(app);
            app.PlotrawfiltdataButton.Enable = true;
            app.DetectArtifactsButton.Enable = true;
            app.filterButton.Enable = true;
            app.LoadArtifactDetectionsButton.Enable = true;
            
            close(f);
        end

        % Value changed function: XmaxEditField
        function XmaxEditFieldValueChanged(app, event)
            app.UIAxes.XLim(2) = app.XmaxEditField.Value;
            
        end

        % Value changed function: XminEditField
        function XminEditFieldValueChanged(app, event)
            app.UIAxes.XLim(1) = app.XminEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {848, 848};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {279, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotrawfiltdataButton = uibutton(app.LeftPanel, 'push');
            app.PlotrawfiltdataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotrawfiltdataButtonPushed, true);
            app.PlotrawfiltdataButton.Enable = 'off';
            app.PlotrawfiltdataButton.Text = 'Plot raw/filt data';
            app.optionalLabel = uilabel(app.LeftPanel);
            app.optionalLabel.Text = '(optional)';
            app.DataFilteringPanel = uipanel(app.LeftPanel);
            app.DataFilteringPanel.Title = 'Data Filtering';
            app.datasamplessecEditFieldLabel = uilabel(app.DataFilteringPanel);
            app.datasamplessecEditFieldLabel.Text = 'data samples/sec';
            app.datasamplessecEditField = uieditfield(app.DataFilteringPanel, 'numeric');
            app.datasamplessecEditField.Value = 30000;
            app.higphasscutoffhzEditFieldLabel = uilabel(app.DataFilteringPanel);
            app.higphasscutoffhzEditFieldLabel.Text = 'higphass cutoff (hz)';
            app.higphasscutoffhzEditField = uieditfield(app.DataFilteringPanel, 'numeric');
            app.higphasscutoffhzEditField.Value = 300;
            app.filterButton = uibutton(app.DataFilteringPanel, 'push');
            app.filterButton.ButtonPushedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.filterButton.Enable = 'off';
            app.filterButton.Text = 'filter';
            app.endzeropadsampsEditFieldLabel = uilabel(app.DataFilteringPanel);
            app.endzeropadsampsEditFieldLabel.Text = 'end zero-pad samps';
            app.endzeropadsampsEditField = uieditfield(app.DataFilteringPanel, 'numeric');
            app.endzeropadsampsEditField.Value = 1000;
            app.ArtifactWaveformDetectionPanel = uipanel(app.LeftPanel);
            app.ArtifactWaveformDetectionPanel.Title = 'Artifact Waveform Detection';
            app.Threshold1EditFieldLabel = uilabel(app.ArtifactWaveformDetectionPanel);
            app.Threshold1EditFieldLabel.Text = 'Threshold1';
            app.Threshold1EditField = uieditfield(app.ArtifactWaveformDetectionPanel, 'numeric');
            app.Thresh1CrossButtonGroup = uibuttongroup(app.ArtifactWaveformDetectionPanel);
            app.Thresh1CrossButtonGroup.Title = 'Thresh1Cross';
            app.risingButton = uitogglebutton(app.Thresh1CrossButtonGroup);
            app.risingButton.Text = 'rising';
            app.fallingButton = uitogglebutton(app.Thresh1CrossButtonGroup);
            app.fallingButton.Text = 'falling';
            app.fallingButton.Value = true;
            app.Threshold2EditFieldLabel = uilabel(app.ArtifactWaveformDetectionPanel);
            app.Threshold2EditFieldLabel.Text = 'Threshold2';
            app.Threshold2EditField = uieditfield(app.ArtifactWaveformDetectionPanel, 'numeric');
            app.Thresh2CrossButtonGroup = uibuttongroup(app.ArtifactWaveformDetectionPanel);
            app.Thresh2CrossButtonGroup.Title = 'Thresh2Cross';
            app.risingButton_2 = uitogglebutton(app.Thresh2CrossButtonGroup);
            app.risingButton_2.Text = 'rising';
            app.risingButton_2.Value = true;
            app.fallingButton_2 = uitogglebutton(app.Thresh2CrossButtonGroup);
            app.fallingButton_2.Text = 'falling';
            app.samplelagbetweenthreshcrossesLabel = uilabel(app.ArtifactWaveformDetectionPanel);
            app.samplelagbetweenthreshcrossesLabel.Text = 'sample-lag between threshcrosses';
            app.minEditFieldLabel = uilabel(app.ArtifactWaveformDetectionPanel);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.ArtifactWaveformDetectionPanel, 'numeric');
            app.maxEditFieldLabel = uilabel(app.ArtifactWaveformDetectionPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.ArtifactWaveformDetectionPanel, 'numeric');
            app.DetectArtifactsButton = uibutton(app.ArtifactWaveformDetectionPanel, 'push');
            app.DetectArtifactsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectArtifactsButtonPushed, true);
            app.DetectArtifactsButton.Enable = 'off';
            app.DetectArtifactsButton.Text = 'Detect Artifacts';
            app.DetectionoffsetEditFieldLabel = uilabel(app.ArtifactWaveformDetectionPanel);
            app.DetectionoffsetEditFieldLabel.Text = 'Detection offset';
            app.DetectionoffsetEditField = uieditfield(app.ArtifactWaveformDetectionPanel, 'numeric');
            app.ArtifactWindowsizeEditFieldLabel = uilabel(app.ArtifactWaveformDetectionPanel);
            app.ArtifactWindowsizeEditFieldLabel.Text = 'Artifact Window size';
            app.ArtifactWindowsizeEditField = uieditfield(app.ArtifactWaveformDetectionPanel, 'numeric');
            app.ArtifactWindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @ArtifactWindowsizeEditFieldValueChanged, true);
            app.ArtifactWindowsizeEditField.Value = 200;
            app.LoadArtifactDetectionsButton = uibutton(app.ArtifactWaveformDetectionPanel, 'push');
            app.LoadArtifactDetectionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadArtifactDetectionsButtonPushed, true);
            app.LoadArtifactDetectionsButton.Enable = 'off';
            app.LoadArtifactDetectionsButton.Text = 'Load Artifact Detections';
            app.mininterpulsesampEditFieldLabel = uilabel(app.ArtifactWaveformDetectionPanel);
            app.mininterpulsesampEditFieldLabel.Text = 'min interpulse samp';
            app.mininterpulsesampEditField = uieditfield(app.ArtifactWaveformDetectionPanel, 'numeric');
            app.mininterpulsesampEditField.Value = 100;
            app.ArtifactDisplaySettingsPanel = uipanel(app.LeftPanel);
            app.ArtifactDisplaySettingsPanel.Title = 'Artifact Display Settings';
            app.YmaxEditFieldLabel = uilabel(app.ArtifactDisplaySettingsPanel);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.ArtifactDisplaySettingsPanel, 'numeric');
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxEditFieldValueChanged, true);
            app.YmaxEditField.Value = 1000;
            app.YminEditFieldLabel = uilabel(app.ArtifactDisplaySettingsPanel);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.ArtifactDisplaySettingsPanel, 'numeric');
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YminEditFieldValueChanged, true);
            app.YminEditField.Value = -1000;
            app.XmaxEditFieldLabel = uilabel(app.ArtifactDisplaySettingsPanel);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.ArtifactDisplaySettingsPanel, 'numeric');
            app.XmaxEditField.ValueChangedFcn = createCallbackFcn(app, @XmaxEditFieldValueChanged, true);
            app.XmaxEditField.Value = 200;
            app.XminEditFieldLabel = uilabel(app.ArtifactDisplaySettingsPanel);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.ArtifactDisplaySettingsPanel, 'numeric');
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @XminEditFieldValueChanged, true);
            app.DataLoadingPanel = uipanel(app.LeftPanel);
            app.DataLoadingPanel.Title = 'Data Loading';
            app.loadmatdataButton = uibutton(app.DataLoadingPanel, 'push');
            app.loadmatdataButton.ButtonPushedFcn = createCallbackFcn(app, @loadmatdataButtonPushed, true);
            app.loadmatdataButton.Text = 'load .mat data';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Detected Artifact Segments')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Peaks Histogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Inter-stimulus interval Histogram')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.DetectedArtifactDisplayNavigationPanel = uipanel(app.RightPanel);
            app.DetectedArtifactDisplayNavigationPanel.Title = 'Detected Artifact Display Navigation';
            app.Button = uibutton(app.DetectedArtifactDisplayNavigationPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<<';
            app.Button_2 = uibutton(app.DetectedArtifactDisplayNavigationPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>>';
            app.NdetectionstodisplayEditFieldLabel = uilabel(app.DetectedArtifactDisplayNavigationPanel);
            app.NdetectionstodisplayEditFieldLabel.Text = 'N detections to display:';
            app.NdetectionstodisplayEditField = uieditfield(app.DetectedArtifactDisplayNavigationPanel, 'numeric');
            app.NdetectionstodisplayEditField.Limits = [1 Inf];
            app.NdetectionstodisplayEditField.ValueDisplayFormat = '%.0f';
            app.NdetectionstodisplayEditField.ValueChangedFcn = createCallbackFcn(app, @NdetectionstodisplayEditFieldValueChanged, true);
            app.NdetectionstodisplayEditField.Value = 1000;
            app.DisplayingDetectionsEditFieldLabel = uilabel(app.DetectedArtifactDisplayNavigationPanel);
            app.DisplayingDetectionsEditFieldLabel.Text = 'Displaying Detections';
            app.DisplayingDetectionsEditField = uieditfield(app.DetectedArtifactDisplayNavigationPanel, 'numeric');
            app.DisplayingDetectionsEditField.Limits = [1 Inf];
            app.DisplayingDetectionsEditField.ValueDisplayFormat = '%.0f';
            app.DisplayingDetectionsEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayingDetectionsEditFieldValueChanged, true);
            app.DisplayingDetectionsEditField.Value = 1;
            app.thruEditFieldLabel = uilabel(app.DetectedArtifactDisplayNavigationPanel);
            app.thruEditFieldLabel.Text = 'thru';
            app.thruEditField = uieditfield(app.DetectedArtifactDisplayNavigationPanel, 'numeric');
            app.thruEditField.Limits = [1 Inf];
            app.thruEditField.ValueChangedFcn = createCallbackFcn(app, @thruEditFieldValueChanged, true);
            app.thruEditField.Editable = 'off';
            app.thruEditField.Value = 1000;
            app.NumofdetectedartsEditFieldLabel = uilabel(app.DetectedArtifactDisplayNavigationPanel);
            app.NumofdetectedartsEditFieldLabel.Text = 'Num of detected arts';
            app.NumofdetectedartsEditField = uieditfield(app.DetectedArtifactDisplayNavigationPanel, 'numeric');
            app.NumofdetectedartsEditField.Limits = [1 Inf];
            app.NumofdetectedartsEditField.ValueDisplayFormat = '%.0f';
            app.NumofdetectedartsEditField.Value = 1;
            app.matdatafileEditFieldLabel = uilabel(app.RightPanel);
            app.matdatafileEditFieldLabel.Text = '.mat data file:';
            app.matdatafileEditField = uieditfield(app.RightPanel, 'text');
            app.matdatafileEditField.Editable = 'off';
            app.matdatafileEditField.Value = '''''';
            app.artifactdetectionfileEditFieldLabel = uilabel(app.RightPanel);
            app.artifactdetectionfileEditFieldLabel.Text = 'artifact detection file:';
            app.artifactdetectionfileEditField = uieditfield(app.RightPanel, 'text');
            app.artifactdetectionfileEditField.Editable = 'off';
            app.artifactdetectionfileEditField.Value = '''''';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadMenu = uimenu(app.Menu);
            app.LoadMenu.Text = 'Load';
            app.loadsettingsMenu_2 = uimenu(app.LoadMenu);
            app.loadsettingsMenu_2.Text = 'load settings';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.Text = 'Save';
            app.saveartifactdetectionsMenu = uimenu(app.SaveMenu);
            app.saveartifactdetectionsMenu.MenuSelectedFcn = createCallbackFcn(app, @saveartifactdetectionsMenuSelected, true);
            app.saveartifactdetectionsMenu.Text = 'save artifact detections';
            app.savesettingsMenu = uimenu(app.SaveMenu);
            app.savesettingsMenu.Text = 'save settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = artifactDetectViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end